classdef GravitationalLensingApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportImageButton               matlab.ui.control.Button
        GRAVITATIONALLENSINGAPPLICATIONLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MainSimulatorTab                matlab.ui.container.Tab
        LoadImageButton                 matlab.ui.control.Button
        SourceSizeSlider                matlab.ui.control.Slider
        SourceSizeSliderLabel           matlab.ui.control.Label
        SourceYPosSlider                matlab.ui.control.Slider
        SourceYPosSliderLabel           matlab.ui.control.Label
        SourceXPosSlider                matlab.ui.control.Slider
        SourceXPosSliderLabel           matlab.ui.control.Label
        LensMassSlider                  matlab.ui.control.Slider
        LensMassSliderLabel             matlab.ui.control.Label
        ChooseBackgroundSourceDropDown  matlab.ui.control.DropDown
        ChooseBackgroundSourceDropDownLabel  matlab.ui.control.Label
        PhysicsLogTextArea              matlab.ui.control.TextArea
        PhysicsLogTextAreaLabel         matlab.ui.control.Label
        CEngineLogTextArea              matlab.ui.control.TextArea
        CEngineLogTextAreaLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AnalyticsandGraphsTab           matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xGrid
        yGrid
        customImage
    end
    
    methods (Access = private)
        function updateSimulation(app)
            % Read values from GUI
            sourceType = app.ChooseBackgroundSourceDropDown.Value;
            massSolar = app.LensMassSlider.Value;
            xSource = app.SourceXPosSlider.Value;
            ySource = app.SourceYPosSlider.Value;
            rSource = app.SourceSizeSlider.Value;

            % Calculate Einstein angle
            thetaE = sqrt(massSolar / 1e11) * 1.5;

            % Generate source image
            sourceImage = generateSource( ...
                app.xGrid, ...
                app.yGrid, ...
                sourceType, ...
                xSource, ...
                ySource, ...
                rSource, ...
                app.customImage);

            % Run C/MEX/M lensing calculation
            [betaX, betaY, cLog] = lensingCore( ...
                "BlackHole_Lens", ...
                massSolar, ...
                app.xGrid, ...
                app.yGrid);

            % Warp source image according to lensing
            lensedImage = interp2( ...
                app.xGrid, ...
                app.yGrid, ...
                sourceImage, ...
                betaX, ...
                betaY, ...
                'linear', ...
                0);

            % Calculate physics
            [physicsData, tableData] = computePhysics( ...
                massSolar, ...
                xSource, ...
                ySource, ...
                thetaE);

            % Display C-engine log
            app.CEngineLogTextArea.Value = cLog;

            % Display physics log
            app.PhysicsLogTextArea.Value = sprintf( ...
                'Total Magnification = %.2fx | Impact u = %.4f | ThetaE = %.4f', ...
                physicsData.totalMagnification, ...
                physicsData.impactU, ...
                physicsData.thetaE);

            % Visual 5: Magnification Table
            % Update table
            app.UITable.Data = tableData;

            % Render analytics graphs
            renderVisualizations( ...
                app.UIAxes, ...
                app.UIAxes2, ...
                app.UIAxes2_3, ...
                app.UIAxes2_2, ...
                app.xGrid, ...
                app.yGrid, ...
                lensedImage, ...
                xSource, ...
                ySource, ...
                thetaE, ...
                tableData);
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, ~)
            % Ask the user where to save the image
            [file, path] = uiputfile( ...
                {'*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'; ...
                '*.pdf', 'PDF File (*.pdf)'}, ...
                'Export Lensed Image');

            % User cancelled
            if isequal(file, 0)
                return;
            end

            % Full file path
            fullFileName = fullfile(path, file);

            % Export the current lensed image
            exportgraphics(app.UIAxes, fullFileName, ...
                'Resolution', 300);

            % Notify the user
            uialert(app.UIFigure, ...
                ['Image successfully exported to:' newline fullFileName], ...
                'Export Complete');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create coordinate grid
            [app.xGrid, app.yGrid] = meshgrid(linspace(-5, 5, 120), linspace(-5, 5, 120));

            % No custom image initially
            app.customImage = [];

            % Run initial simulation
            updateSimulation(app);
        end

        % Value changed function: LensMassSlider
        function LensMassSliderValueChanged(app, event)
            updateSimulation(app);
        end

        % Value changed function: SourceXPosSlider
        function SourceXPosSliderValueChanged(app, event)
            updateSimulation(app);
        end

        % Value changed function: SourceYPosSlider
        function SourceYPosSliderValueChanged(app, event)
            updateSimulation(app);
        end

        % Value changed function: SourceSizeSlider
        function SourceSizeSliderValueChanged(app, event)
            updateSimulation(app);
        end

        % Value changed function: ChooseBackgroundSourceDropDown
        function ChooseBackgroundSourceDropDownValueChanged(app, event)
            updateSimulation(app);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Open file selection dialog for supported image formats
            [file, path] = uigetfile( ...
            {'*.jpg;*.jpeg;*.png;*.tif;*.tiff', ...
            'Image Files'});

            % Stop if the user cancels the file selection
            if isequal(file, 0)
                return;
            end

            % Load the selected image
            app.customImage = imread(fullfile(path, file));

            % Switch source selector to astronomical image
            app.ChooseBackgroundSourceDropDown.Value = ...
                'Astronomical Image';

            % Update the simulation using the loaded image
            updateSimulation(app);
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed2(app, event)
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn( ...
            app, @ExportImageButtonPushed, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainSimulatorTab = uitab(app.TabGroup);
            app.MainSimulatorTab.Title = 'Main Simulator';
            app.UIAxes = uiaxes(app.MainSimulatorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CEngineLogTextAreaLabel = uilabel(app.MainSimulatorTab);
            app.CEngineLogTextAreaLabel.Text = 'C-Engine Log';
            app.CEngineLogTextArea = uitextarea(app.MainSimulatorTab);
            app.PhysicsLogTextAreaLabel = uilabel(app.MainSimulatorTab);
            app.PhysicsLogTextAreaLabel.Text = 'Physics Log';
            app.PhysicsLogTextArea = uitextarea(app.MainSimulatorTab);
            app.ChooseBackgroundSourceDropDownLabel = uilabel(app.MainSimulatorTab);
            app.ChooseBackgroundSourceDropDownLabel.Text = 'Choose Background Source';
            app.ChooseBackgroundSourceDropDown = uidropdown(app.MainSimulatorTab);
            app.ChooseBackgroundSourceDropDown.Items = {'Gaussian Profile', 'Astronomical Image'};
            app.ChooseBackgroundSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseBackgroundSourceDropDownValueChanged, true);
            app.ChooseBackgroundSourceDropDown.Value = 'Gaussian Profile';
            app.LensMassSliderLabel = uilabel(app.MainSimulatorTab);
            app.LensMassSliderLabel.Text = 'Lens Mass';
            app.LensMassSlider = uislider(app.MainSimulatorTab);
            app.LensMassSlider.Limits = [10000000000 1000000000000];
            app.LensMassSlider.ValueChangedFcn = createCallbackFcn(app, @LensMassSliderValueChanged, true);
            app.LensMassSlider.Value = 100000000000;
            app.SourceXPosSliderLabel = uilabel(app.MainSimulatorTab);
            app.SourceXPosSliderLabel.Text = 'Source X Pos';
            app.SourceXPosSlider = uislider(app.MainSimulatorTab);
            app.SourceXPosSlider.Limits = [-3 3];
            app.SourceXPosSlider.ValueChangedFcn = createCallbackFcn(app, @SourceXPosSliderValueChanged, true);
            app.SourceYPosSliderLabel = uilabel(app.MainSimulatorTab);
            app.SourceYPosSliderLabel.Text = 'Source Y Pos';
            app.SourceYPosSlider = uislider(app.MainSimulatorTab);
            app.SourceYPosSlider.Limits = [-3 3];
            app.SourceYPosSlider.ValueChangedFcn = createCallbackFcn(app, @SourceYPosSliderValueChanged, true);
            app.SourceSizeSliderLabel = uilabel(app.MainSimulatorTab);
            app.SourceSizeSliderLabel.Text = 'Source Size';
            app.SourceSizeSlider = uislider(app.MainSimulatorTab);
            app.SourceSizeSlider.Limits = [0.1 2];
            app.SourceSizeSlider.ValueChangedFcn = createCallbackFcn(app, @SourceSizeSliderValueChanged, true);
            app.SourceSizeSlider.Value = 1;
            app.LoadImageButton = uibutton(app.MainSimulatorTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.AnalyticsandGraphsTab = uitab(app.TabGroup);
            app.AnalyticsandGraphsTab.Title = 'Analytics and Graphs';
            app.UIAxes2 = uiaxes(app.AnalyticsandGraphsTab);
            title(app.UIAxes2, 'Gravitational Deflection Field')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.AnalyticsandGraphsTab);
            title(app.UIAxes2_2, 'Image Root Locations')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.AnalyticsandGraphsTab);
            title(app.UIAxes2_3, 'Brightness Line Profile')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UITable = uitable(app.AnalyticsandGraphsTab);
            app.UITable.ColumnName = {'Image'; 'Angular Position'; 'Magnification'};
            app.UITable.RowName = {};
            app.GRAVITATIONALLENSINGAPPLICATIONLabel = uilabel(app.UIFigure);
            app.GRAVITATIONALLENSINGAPPLICATIONLabel.Text = 'GRAVITATIONAL LENSING APPLICATION';
            app.ExportImageButton = uibutton(app.UIFigure, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed2, true);
            app.ExportImageButton.Text = 'Export Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GravitationalLensingApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end